classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Menu        matlab.ui.container.Menu
        Menu_3      matlab.ui.container.Menu
        Menu_2      matlab.ui.container.Menu
        UIAxes      matlab.ui.control.UIAxes
        UIAxes2     matlab.ui.control.UIAxes
        Label       matlab.ui.control.Label
        Label_2     matlab.ui.control.Label
        Label_3     matlab.ui.control.Label
        Button      matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        Button_3    matlab.ui.control.Button
        Button_4    matlab.ui.control.Button
        Button_5    matlab.ui.control.Button
        Button_6    matlab.ui.control.Button
        Button_7    matlab.ui.control.Button
        Button_8    matlab.ui.control.Button
        Button_9    matlab.ui.control.Button
        Button_10   matlab.ui.control.Button
        Button_11   matlab.ui.control.Button
        UIAxes2_2   matlab.ui.control.UIAxes
        UIAxes2_3   matlab.ui.control.UIAxes
        UIAxes2_4   matlab.ui.control.UIAxes
        UIAxes2_5   matlab.ui.control.UIAxes
        UIAxes2_6   matlab.ui.control.UIAxes
        UIAxes2_7   matlab.ui.control.UIAxes
        UIAxes2_8   matlab.ui.control.UIAxes
        UIAxes2_9   matlab.ui.control.UIAxes
        UIAxes2_10  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        I 
        I1
        I4
        I5
        I6
        I7
        I8
        I9
        I10
        I11
        I12
        I13
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            [filename filepath] = uigetfile('.jpg', '选择需要识别的图像');
            file = strcat(filepath, filename);
            img = imread(file);
            app.I=img;
            imshow(img,'Parent',app.UIAxes);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            img1 = rgb2gray(app.I);    % RGB图像转灰度图像
            app.I1=img1;
            imshow(img1,'Parent',app.UIAxes2);
%             imhist(img1);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            img4 = edge(app.I1, 'roberts', 0.15, 'both');
            app.I4=img4;
            imshow(img4,'Parent',app.UIAxes2_2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            se=[1;1;1];
            img5 = imerode(app.I4, se);
            app.I5=img5;
            imshow(img5,'Parent',app.UIAxes2_3);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            se = strel('rectangle', [30, 30]);
            img6 = imclose(app.I5, se);
            app.I6=img6;
            imshow(img6,'Parent',app.UIAxes2_4);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            img7 = bwareaopen(app.I6, 2200);
            app.I7=img7;
            imshow(img7,'Parent',app.UIAxes2_5);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            [y, x, z] = size(app.I7);
            img8 = double(app.I7);    % 转成双精度浮点型
            
            % 车牌的蓝色区域
            % Y方向
            blue_Y = zeros(y, 1);
            for i = 1:y
                for j = 1:x
                    if(img8(i, j) == 1) % 判断车牌位置区域
                        blue_Y(i, 1) = blue_Y(i, 1) + 1;    % 像素点统计
                    end
                end
            end
            
            % 找到Y坐标的最小值
            img_Y1 = 1;
            while (blue_Y(img_Y1) < 5) && (img_Y1 < y)
                img_Y1 = img_Y1 + 1;
            end
            
            % 找到Y坐标的最大值
            img_Y2 = y;
            while (blue_Y(img_Y2) < 5) && (img_Y2 > img_Y1)
                img_Y2 = img_Y2 - 1;
            end
            
            % x方向
            blue_X = zeros(1, x);
            for j = 1:x
                for i = 1:y
                    if(img8(i, j) == 1) % 判断车牌位置区域
                        blue_X(1, j) = blue_X(1, j) + 1;
                    end
                end
            end
            
            % 找到x坐标的最小值
            img_X1 = 1;
            while (blue_X(1, img_X1) < 5) && (img_X1 < x)
                img_X1 = img_X1 + 1;
            end
            
            % 找到x坐标的最小值
            img_X2 = x;
            while (blue_X(1, img_X2) < 5) && (img_X2 > img_X1)
                img_X2 = img_X2 - 1;
            end


            % 对图像进行裁剪
            img9 =app.I(img_Y1:img_Y2,img_X1:img_X2,:);
            imshow(img9,'Parent',app.UIAxes2_6);
            app.I9=img9;
            % 保存提取出来的车牌图像
            imwrite(img9, '车牌图像.jpg');
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %             plate_img = imread(app.I9);
            
                        % 转换成灰度图像
                        plate_img1 = rgb2gray(app.I9);    % RGB图像转灰度图像
                        imshow(plate_img1,'Parent',app.UIAxes2_7);
                        app.I10=plate_img1;
%                         imhist(plate_img1);
            
%             plate_img1 = rgb2gray(app.I9);    % RGB图像转灰度图像
%             app.I1=img1;
%             imshow(plate_img1,'Parent',app.UIAxes2_7);
%             imhist(plate_img1);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            plate_img2 = histeq(app.I10);
            app.I11=plate_img2;
            imshow(plate_img2,'Parent',app.UIAxes2_8);
%           imhist(plate_img2);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            plate_img3 = im2bw(app.I11, 0.76);
            app.I12=plate_img3;
            imshow(plate_img3,'Parent',app.UIAxes2_9);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            plate_img4 = medfilt2(app.I12);
            app.I13=plate_img4;
            imshow(plate_img4,'Parent',app.UIAxes2_10);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)


            %%图像识别
            plate_img5 = my_imsplit(app.I13);
            [m, n] = size(plate_img5);
            
            s = sum(plate_img5);    %sum(x)就是竖向相加，求每列的和，结果是行向量;
            j = 1;
            k1 = 1;
            k2 = 1;
            while j ~= n
                while s(j) == 0
                    j = j + 1;
                end
                k1 = j;
                while s(j) ~= 0 && j <= n-1
                    j = j + 1;
                end
                k2 = j + 1;
                if k2 - k1 > round(n / 6.5)
                    [val, num] = min(sum(plate_img5(:, [k1+5:k2-5])));
                    plate_img5(:, k1+num+5) = 0;
                end
            end
            
            y1 = 10;
            y2 = 0.25;
            flag = 0;
            word1 = [];
            while flag == 0
                [m, n] = size(plate_img5);
                left = 1;
                width = 0;
                while sum(plate_img5(:, width+1)) ~= 0
                    width = width + 1;
                end
                if width < y1
                    plate_img5(:, [1:width]) = 0;
                    plate_img5 = my_imsplit(plate_img5);
                else
                    temp = my_imsplit(imcrop(plate_img5, [1,1,width,m]));
                    [m, n] = size(temp);
                    all = sum(sum(temp));
                    two_thirds=sum(sum(temp([round(m/3):2*round(m/3)],:)));
                    if two_thirds/all > y2
                        flag = 1;
                        word1 = temp;
                    end
                    plate_img5(:, [1:width]) = 0;
                    plate_img5 = my_imsplit(plate_img5);
                end
            end
            
%             figure;
            %             subplot(2,4,1), imshow(plate_img5);
            
            % 分割出第二个字符
            [word2,plate_img5]=getword(plate_img5);
            %             subplot(2,4,2), imshow(plate_img5);
            % 分割出第三个字符
            [word3,plate_img5]=getword(plate_img5);
            %             subplot(2,4,3), imshow(plate_img5);
            % 分割出第四个字符
            [word4,plate_img5]=getword(plate_img5);
            %             subplot(2,4,4), imshow(plate_img5);
            % 分割出第五个字符
            [word5,plate_img5]=getword(plate_img5);
            %             subplot(2,3,4), imshow(plate_img5);
            % 分割出第六个字符
            [word6,plate_img5]=getword(plate_img5);
            %             subplot(2,3,5), imshow(plate_img5);
            % 分割出第七个字符
            [word7,plate_img5]=getword(plate_img5);
            %             subplot(2,3,6), imshow(plate_img5);
            
%             figure;
            %             subplot(5,7,1),imshow(word1),title('1');
            %             subplot(5,7,2),imshow(word2),title('2');
            %             subplot(5,7,3),imshow(word3),title('3');
            %             subplot(5,7,4),imshow(word4),title('4');
            %             subplot(5,7,5),imshow(word5),title('5');
            %             subplot(5,7,6),imshow(word6),title('6');
            %             subplot(5,7,7),imshow(word7),title('7');
            
            word1=imresize(word1,[40 20]);%imresize对图像做缩放处理，常用调用格式为：B=imresize(A,ntimes,method)；其中method可选nearest,bilinear（双线性）,bicubic,box,lanczors2,lanczors3等
            word2=imresize(word2,[40 20]);
            word3=imresize(word3,[40 20]);
            word4=imresize(word4,[40 20]);
            word5=imresize(word5,[40 20]);
            word6=imresize(word6,[40 20]);
            word7=imresize(word7,[40 20]);
            
            %             subplot(5,7,15),imshow(word1),title('11');
            %             subplot(5,7,16),imshow(word2),title('22');
            %             subplot(5,7,17),imshow(word3),title('33');
            %             subplot(5,7,18),imshow(word4),title('44');
            %             subplot(5,7,19),imshow(word5),title('55');
            %             subplot(5,7,20),imshow(word6),title('66');
            %             subplot(5,7,21),imshow(word7),title('77');
            
            imwrite(word1,'1.jpg'); % 创建七位车牌字符图像
            imwrite(word2,'2.jpg');
            imwrite(word3,'3.jpg');
            imwrite(word4,'4.jpg');
            imwrite(word5,'5.jpg');
            imwrite(word6,'6.jpg');
            imwrite(word7,'7.jpg');
            
            %% 进行字符识别
            liccode=char(['0':'9' 'A':'Z' '京辽鲁陕苏豫浙贵']);%建立自动识别字符代码表；'京津沪渝港澳吉辽鲁豫冀鄂湘晋青皖苏赣浙闽粤琼台陕甘云川贵黑藏蒙桂新宁'
            % 编号：0-9分别为 1-10;A-Z分别为 11-36;
            % 京  津  沪  渝  港  澳  吉  辽  鲁  豫  冀  鄂  湘  晋  青  皖  苏
            % 赣  浙  闽  粤  琼  台  陕  甘  云  川  贵  黑  藏  蒙  桂  新  宁
            % 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59
            % 60 61 62 63 64 65 66 67 68 69 70
            subBw2 = zeros(40, 20);
            num = 1;   % 车牌位数
            for i = 1:7
                ii = int2str(i);    % 将整型数据转换为字符串型数据
                word = imread([ii,'.jpg']); % 读取之前分割出的字符的图片
                segBw2 = imresize(word, [40,20], 'nearest');    % 调整图片的大小
                segBw2 = im2bw(segBw2, 0.5);    % 图像二值化
                if i == 1   % 字符第一位为汉字，定位汉字所在字段
                    kMin = 37;
                    kMax = 44;
                elseif i == 2   % 第二位为英文字母，定位字母所在字段
                    kMin = 11;
                    kMax = 36;
                elseif i >= 3   % 第三位开始就是数字了，定位数字所在字段
                    kMin = 1;
                    kMax = 36;
                end
                
                l = 1;
                for k = kMin : kMax
                    fname = strcat('字符模板\',liccode(k),'.jpg');  % 根据字符库找到图片模板
                    samBw2 = imread(fname); % 读取模板库中的图片
                    samBw2 = im2bw(samBw2, 0.5);    % 图像二值化
                    
                    % 将待识别图片与模板图片做差
                    for i1 = 1:40
                        for j1 = 1:20
                            subBw2(i1, j1) = segBw2(i1, j1) - samBw2(i1 ,j1);
                        end
                    end
                    
                    % 统计两幅图片不同点的个数，并保存下来
                    Dmax = 0;
                    for i2 = 1:40
                        for j2 = 1:20
                            if subBw2(i2, j2) ~= 0
                                Dmax = Dmax + 1;
                            end
                        end
                    end
                    error(l) = Dmax;
                    l = l + 1;
                end
                
                % 找到图片差别最少的图像
                errorMin = min(error);
                findc = find(error == errorMin);
                %     error
                %     findc
                
                % 根据字库，对应到识别的字符
                Code(num*2 - 1) = liccode(findc(1) + kMin - 1);
                Code(num*2) = ' ';
                num = num + 1;
                
                
            end
            
            % 显示识别结果
            disp(Code);
            msgbox(Code,'识别出的车牌号');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '开始';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '选择图片';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '帮助';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原图')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '图像预处理';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '车牌定位';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '车牌识别';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '灰度处理';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '边缘检测';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '图像腐蚀';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '图像平滑';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '移除对象';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '图像切割';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '灰度处理';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '直方图均衡化';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '二值化';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '中值滤波';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '字符识别';
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, '')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, '')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, '')
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            app.UIAxes2_5 = uiaxes(app.UIFigure);
            title(app.UIAxes2_5, '')
            xlabel(app.UIAxes2_5, '')
            ylabel(app.UIAxes2_5, '')
            app.UIAxes2_5.XTick = [];
            app.UIAxes2_5.YTick = [];
            app.UIAxes2_6 = uiaxes(app.UIFigure);
            title(app.UIAxes2_6, '')
            xlabel(app.UIAxes2_6, '')
            ylabel(app.UIAxes2_6, '')
            app.UIAxes2_6.XTick = [];
            app.UIAxes2_6.YTick = [];
            app.UIAxes2_7 = uiaxes(app.UIFigure);
            title(app.UIAxes2_7, '')
            xlabel(app.UIAxes2_7, '')
            ylabel(app.UIAxes2_7, '')
            app.UIAxes2_7.XTick = [];
            app.UIAxes2_7.YTick = [];
            app.UIAxes2_8 = uiaxes(app.UIFigure);
            title(app.UIAxes2_8, '')
            xlabel(app.UIAxes2_8, '')
            ylabel(app.UIAxes2_8, '')
            app.UIAxes2_8.XTick = [];
            app.UIAxes2_8.YTick = [];
            app.UIAxes2_9 = uiaxes(app.UIFigure);
            title(app.UIAxes2_9, '')
            xlabel(app.UIAxes2_9, '')
            ylabel(app.UIAxes2_9, '')
            app.UIAxes2_9.XTick = [];
            app.UIAxes2_9.YTick = [];
            app.UIAxes2_10 = uiaxes(app.UIFigure);
            title(app.UIAxes2_10, '')
            xlabel(app.UIAxes2_10, '')
            ylabel(app.UIAxes2_10, '')
            app.UIAxes2_10.XTick = [];
            app.UIAxes2_10.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end